classdef RA15_Control_Panel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        RightPanel              matlab.ui.container.Panel
        RobotControlPanel       matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        JointPanel              matlab.ui.container.Panel
        PTSpinner               matlab.ui.control.Spinner
        VTSpinner               matlab.ui.control.Spinner
        VTMaxCheckBox           matlab.ui.control.CheckBox
        PTEnableCheckBox        matlab.ui.control.CheckBox
        PTSlider                matlab.ui.control.Slider
        VTSlider                matlab.ui.control.Slider
        PVPlot                  matlab.ui.control.UIAxes
        JointPanel_2            matlab.ui.container.Panel
        PTSpinner_2             matlab.ui.control.Spinner
        VTSpinner_2             matlab.ui.control.Spinner
        VTMaxCheckBox_2         matlab.ui.control.CheckBox
        PTEnableCheckBox_2      matlab.ui.control.CheckBox
        PTSlider_2              matlab.ui.control.Slider
        VTSlider_2              matlab.ui.control.Slider
        PVPlot_2                matlab.ui.control.UIAxes
        JointPanel_3            matlab.ui.container.Panel
        PTSpinner_3             matlab.ui.control.Spinner
        VTSpinner_3             matlab.ui.control.Spinner
        VTMaxCheckBox_3         matlab.ui.control.CheckBox
        PTEnableCheckBox_3      matlab.ui.control.CheckBox
        PTSlider_3              matlab.ui.control.Slider
        VTSlider_3              matlab.ui.control.Slider
        PVPlot_3                matlab.ui.control.UIAxes
        JointPanel_4            matlab.ui.container.Panel
        PTSpinner_4             matlab.ui.control.Spinner
        VTSpinner_4             matlab.ui.control.Spinner
        VTMaxCheckBox_4         matlab.ui.control.CheckBox
        PTEnableCheckBox_4      matlab.ui.control.CheckBox
        PTSlider_4              matlab.ui.control.Slider
        VTSlider_4              matlab.ui.control.Slider
        PVPlot_4                matlab.ui.control.UIAxes
        JointPanel_5            matlab.ui.container.Panel
        PTSpinner_5             matlab.ui.control.Spinner
        VTSpinner_5             matlab.ui.control.Spinner
        VTMaxCheckBox_5         matlab.ui.control.CheckBox
        PTEnableCheckBox_5      matlab.ui.control.CheckBox
        PTSlider_5              matlab.ui.control.Slider
        VTSlider_5              matlab.ui.control.Slider
        PVPlot_5                matlab.ui.control.UIAxes
        JointPanel_6            matlab.ui.container.Panel
        PTSpinner_6             matlab.ui.control.Spinner
        VTSpinner_6             matlab.ui.control.Spinner
        VTMaxCheckBox_6         matlab.ui.control.CheckBox
        PTEnableCheckBox_6      matlab.ui.control.CheckBox
        PTSlider_6              matlab.ui.control.Slider
        VTSlider_6              matlab.ui.control.Slider
        PVPlot_6                matlab.ui.control.UIAxes
        LeftPanel               matlab.ui.container.Panel
        ConnectionPanel         matlab.ui.container.Panel
        BluetoothNameLabel      matlab.ui.control.Label
        BluetoothNameEditField  matlab.ui.control.EditField
        ConnectToNXTButton      matlab.ui.control.Button
        RXTXLabel               matlab.ui.control.Label
        ClearHistoryButton      matlab.ui.control.Button
        WritetoXLSXButton       matlab.ui.control.Button
    end

        
    properties (Access = private)
        JointPanels             matlab.ui.container.Panel
        PTSpinners              matlab.ui.control.Spinner
        VTSpinners              matlab.ui.control.Spinner
        VTMaxCheckBoxes         matlab.ui.control.CheckBox
        PTEnableCheckBoxes      matlab.ui.control.CheckBox
        PTSliders               matlab.ui.control.Slider
        VTSliders               matlab.ui.control.Slider
        PVPlots                 matlab.ui.control.UIAxes
        
        rcx                     = 0;
        ea1                     = 0;
        ea2                     = 0;
        ea3                     = 0;
        
        nxt                     NXTConnection
        bluetoothName           char
        bluetoothChannel        = 1;
        nxtTransmitInterval     = 100;
        
        pcTransmitInterval      = 200;
        pcTransmitTimer         timer
        
        plotUpdateInterval      = 500;
        plotUpdateTimer         timer
        plotTimeRange           = 10000;
        
        NO_HOME_COLOR           = [0.9412 0.9412 0.9412];
        HOME_COLOR              = [0.7608 0.7373 0.8902];
    end
       
    methods (Access = private)
        
        function initJointPanels(app)
            app.JointPanels(1) = app.JointPanel;
            app.JointPanels(2) = app.JointPanel_2;
            app.JointPanels(3) = app.JointPanel_3;
            app.JointPanels(4) = app.JointPanel_4;
            app.JointPanels(5) = app.JointPanel_5;
            app.JointPanels(6) = app.JointPanel_6;
            
            for i=1:6
                app.JointPanels(i).UserData = i;
                app.JointPanels(i).BackgroundColor = app.NO_HOME_COLOR;
                app.setJointPanelTitle(i, jpmtr(i).phome, 0, 0, false);
            end
        end
                
        function initPVPlots(app)
            app.PVPlots(1) = app.PVPlot;
            app.PVPlots(2) = app.PVPlot_2;
            app.PVPlots(3) = app.PVPlot_3;
            app.PVPlots(4) = app.PVPlot_4;
            app.PVPlots(5) = app.PVPlot_5;
            app.PVPlots(6) = app.PVPlot_6;
            
            for i=1:6
                pmin = jpmtr(i).pmin;
                pmax = jpmtr(i).pmax;
                vmax = jpmtr(i).vmax;
                
                yyaxis(app.PVPlots(i),'left');
                app.PVPlots(i).YLabel.String='Position (deg)';
                app.PVPlots(i).YLim = [pmin, pmax];
                app.PVPlots(i).YColor = [0 0 1];
                app.PVPlots(i).YMinorTick = 'on';
                app.PVPlots(i).YGrid = 'on';
                
                
                yyaxis(app.PVPlots(i),'right');
                app.PVPlots(i).YLabel.String='Velocity (deg/s)';
                app.PVPlots(i).YLim = [-vmax, vmax];
                app.PVPlots(i).YColor = [1 0 0];
                app.PVPlots(i).YMinorTick = 'on';
                
                disableDefaultInteractivity(app.PVPlots(i));
            end
        end
        
        function initPTSpinnersAndSliders(app)
            app.PTSpinners(1) = app.PTSpinner;
            app.PTSpinners(2) = app.PTSpinner_2;
            app.PTSpinners(3) = app.PTSpinner_3;
            app.PTSpinners(4) = app.PTSpinner_4;
            app.PTSpinners(5) = app.PTSpinner_5;
            app.PTSpinners(6) = app.PTSpinner_6;
            
            app.PTSliders(1) = app.PTSlider;
            app.PTSliders(2) = app.PTSlider_2;
            app.PTSliders(3) = app.PTSlider_3;
            app.PTSliders(4) = app.PTSlider_4;
            app.PTSliders(5) = app.PTSlider_5;
            app.PTSliders(6) = app.PTSlider_6;
            
            for i=1:6
                app.PTSpinners(i).UserData = i;
                app.PTSliders(i).UserData = i;
                
                app.PTSpinners(i).ValueChangedFcn = createCallbackFcn(app, @PTSpinnerValueChange, true);
                app.PTSpinners(i).ValueChangingFcn = createCallbackFcn(app, @PTSpinnerValueChange, true);
                app.PTSliders(i).ValueChangingFcn = createCallbackFcn(app, @PTSliderValueChanging, true);
                
                pmax = jpmtr(i).pmax;
                pmin = jpmtr(i).pmin;
                prest = jpmtr(i).prest;
                app.PTSpinners(i).Limits = [pmin, pmax];
                app.PTSliders(i).Limits = [pmin, pmax];
                yyaxis(app.PVPlots(i),'left');
                app.PTSliders(i).MajorTicks = app.PVPlots(i).YTick;
                app.setPTSpinnerSliderValue(i, prest);
            end
        end
        
        function initVTSpinnersAndSliders(app)
            app.VTSpinners(1) = app.VTSpinner;
            app.VTSpinners(2) = app.VTSpinner_2;
            app.VTSpinners(3) = app.VTSpinner_3;
            app.VTSpinners(4) = app.VTSpinner_4;
            app.VTSpinners(5) = app.VTSpinner_5;
            app.VTSpinners(6) = app.VTSpinner_6;
            
            app.VTSliders(1) = app.VTSlider;
            app.VTSliders(2) = app.VTSlider_2;
            app.VTSliders(3) = app.VTSlider_3;
            app.VTSliders(4) = app.VTSlider_4;
            app.VTSliders(5) = app.VTSlider_5;
            app.VTSliders(6) = app.VTSlider_6;
            
            for i=1:6
                app.VTSpinners(i).UserData = i;
                app.VTSliders(i).UserData = i;
                
                app.VTSpinners(i).ValueChangedFcn = createCallbackFcn(app, @VTSpinnerValueChange, true);
                app.VTSpinners(i).ValueChangingFcn = createCallbackFcn(app, @VTSpinnerValueChange, true);
                app.VTSliders(i).ValueChangingFcn = createCallbackFcn(app, @VTSliderValueChanging, true);
                app.VTSliders(i).ValueChangedFcn = createCallbackFcn(app, @VTSliderValueChanged, true);
                
                vmax = jpmtr(i).vmax;
                app.VTSpinners(i).Limits = [-vmax, vmax];
                app.VTSliders(i).Limits = [-vmax, vmax];
                yyaxis(app.PVPlots(i),'right');
                app.VTSliders(i).MajorTicks = app.PVPlots(i).YTick;
                app.setVTSpinnerSliderValue(i, 0);
            end
        end
                
                        
        function initPTEnableCheckBoxes(app)           
            app.PTEnableCheckBoxes(1) = app.PTEnableCheckBox;
            app.PTEnableCheckBoxes(2) = app.PTEnableCheckBox_2;
            app.PTEnableCheckBoxes(3) = app.PTEnableCheckBox_3;
            app.PTEnableCheckBoxes(4) = app.PTEnableCheckBox_4;
            app.PTEnableCheckBoxes(5) = app.PTEnableCheckBox_5;
            app.PTEnableCheckBoxes(6) = app.PTEnableCheckBox_6;
            
            for i=1:6
                app.PTEnableCheckBoxes(i).UserData = i;
                app.PTEnableCheckBoxes(i).ValueChangedFcn = createCallbackFcn(app, @PTCheckboxValueChanged, true);
            end
        end
        
        function initVTMaxCheckBoxes(app)
            app.VTMaxCheckBoxes(1) = app.VTMaxCheckBox;
            app.VTMaxCheckBoxes(2) = app.VTMaxCheckBox_2;
            app.VTMaxCheckBoxes(3) = app.VTMaxCheckBox_3;
            app.VTMaxCheckBoxes(4) = app.VTMaxCheckBox_4;
            app.VTMaxCheckBoxes(5) = app.VTMaxCheckBox_5;
            app.VTMaxCheckBoxes(6) = app.VTMaxCheckBox_6;
            
            for i=1:6
                app.VTMaxCheckBoxes(i).UserData = i;
                app.VTMaxCheckBoxes(i).ValueChangedFcn = createCallbackFcn(app, @VTCheckboxValueChanged, true);
            end
        end
                                
        
        function initTimers(app)
            app.pcTransmitTimer = timer('Name', 'pcTransmit',   ...
                'TimerFcn', @app.sendPacketFromUI,        ...
                'Period', app.pcTransmitInterval/1000,          ...
                'ExecutionMode', 'fixedRate',                   ...
                'BusyMode', 'drop',                             ...
                'StartDelay', 5);
                       
            app.plotUpdateTimer = timer('Name', 'plotUpdate',   ...
                'TimerFcn', @app.updatePlots,                   ...
                'Period', app.plotUpdateInterval/1000,          ...
                'ExecutionMode', 'fixedRate',                   ...
                'BusyMode', 'drop',                             ...
                'StartDelay', 5);
        end
        
        function startTimers(app)
            start(app.pcTransmitTimer);
            start(app.plotUpdateTimer);
        end
        
        function setJointPanelTitle(app, joint_index, pos, vel, pwm, home)
            app.JointPanels(joint_index).Title =  ...
                sprintf('J%d:  %0.2f deg,   %0.2f deg/s,   %d%% PWM',  ...
                         joint_index, pos, vel, pwm);
            if home == true && isequal(app.JointPanels(joint_index).BackgroundColor, app.NO_HOME_COLOR)
                app.JointPanels(joint_index).BackgroundColor = app.HOME_COLOR;
            elseif home == false && isequal(app.JointPanels(joint_index).BackgroundColor, app.HOME_COLOR)
                app.JointPanels(joint_index).BackgroundColor = app.NO_HOME_COLOR;
            end
        end
        
        function setPTSpinnerSliderValue(app, joint_index, pos_tgt)
            if pos_tgt == NXTConnection.DISABLE_PT
                app.PTSpinners(joint_index).Value = jpmtr(joint_index).prest;
                app.PTSliders(joint_index).Value = jpmtr(joint_index).prest;
                app.PTSpinners(joint_index).Enable = 'off';
                app.PTSliders(joint_index).Enable = 'off';
            else
                app.PTSpinners(joint_index).Value = pos_tgt;
                app.PTSliders(joint_index).Value = pos_tgt;
                app.PTSpinners(joint_index).Enable = 'on';
                app.PTSliders(joint_index).Enable = 'on';
            end
        end
        
        function setVTSpinnerSliderValue(app, joint_index, vel_tgt)
            if vel_tgt == NXTConnection.DISABLE_VT
                app.VTSpinners(joint_index).Value = 0;
                app.VTSliders(joint_index).Value = 0;
                app.VTSpinners(joint_index).Enable = 'off';
                app.VTSliders(joint_index).Enable = 'off';
            else
                app.VTSpinners(joint_index).Value = vel_tgt;
                app.VTSliders(joint_index).Value = vel_tgt;
                app.VTSpinners(joint_index).Enable = 'on';
                app.VTSliders(joint_index).Enable = 'on';
            end
        end
        
        
        function setConnectionPanelValue(app, connected)
            if connected == true
                app.WritetoXLSXButton.Enable = 'off';
                app.ConnectToNXTButton.Text = 'Disconnect from NXT';
                app.ConnectToNXTButton.Enable = 'on';
            else
                app.WritetoXLSXButton.Enable = 'on';
                app.ConnectToNXTButton.Text = 'Connect to NXT';
                app.ConnectToNXTButton.Enable = 'on';
            end
        end
             
        
        % Updates plots from nxt.history table
        function updatePlots(app, timer, event)          
            persistent PVarNames;
            persistent VVarNames;
            persistent PTVarNames;
            persistent VTVarNames;
            
            % get names of variables to plot in advance
            if isempty(PVarNames)   
                for ji=1:6
                    PVarNames{ji} = ['j',num2str(ji),'p'];
                    VVarNames{ji} = ['j',num2str(ji),'v'];
                    PTVarNames{ji} = ['j',num2str(ji),'pt'];
                    VTVarNames{ji} = ['j',num2str(ji),'vt'];
                end
            end
            
            % only plot if there is something to plot
            if app.nxt.lastRow > 0
                
                % get range of history to plot
                upperIndex = app.nxt.lastRow;
                lowerIndex = app.nxt.lastRow - app.plotTimeRange/app.nxtTransmitInterval;
                if lowerIndex < 1
                    lowerIndex = 1;
                end
                dataRange = [lowerIndex:upperIndex];
                
                lowerTime = app.nxt.history.systick(lowerIndex);
                upperTime = lowerTime + app.plotTimeRange();
                timeRange = [lowerTime:1000:upperTime];
                
                % plot P, PT, V, T on all 6 axes
                for ji=1:6
                    app.PVPlots(ji).XLim = [lowerTime upperTime];
                    app.PVPlots(ji).XTick = timeRange;
                    
                    yyaxis(app.PVPlots(ji),'left')
                    reduce_plot(app.PVPlots(ji), ...
                         app.nxt.history.systick(dataRange)', ...
                         app.nxt.history.(PVarNames{ji})(dataRange)', 'b'        );
                    yyaxis(app.PVPlots(ji),'right')
                    reduce_plot(app.PVPlots(ji), ...
                         app.nxt.history.systick(dataRange)', ...
                         app.nxt.history.(VVarNames{ji})(dataRange)', 'r'        );             
                    
                end
            end
        end
        
        
        % Updates UI components from newly-received packet
        function updateUIFromPacket(app, returnData)
            %  returnData.nxt:       struct containing values extracted from nxt packet
            %  returnData.processed: struct containing additional values calculated from returnData.nxt
            %                        (or empty struct if no processing function was provided to the class constructor)
            
            persistent nxtVarStartIndex;
            if isempty(nxtVarStartIndex)
                nxtVarStartIndex = strfind(strcmp('j1p', NXTConnection.NXT_PACKET_VARS)', 1);
            end
            
            var = nxtVarStartIndex;
            for ji=1:6
                p = returnData.nxt.(NXTConnection.NXT_PACKET_VARS{var});       var = var+1;
                v = returnData.nxt.(NXTConnection.NXT_PACKET_VARS{var});       var = var+1;
                pwm = returnData.nxt.(NXTConnection.NXT_PACKET_VARS{var});     var = var+1;
                home = (bitand(returnData.nxt.tmux, jpmtr(ji).tmux_mask) == 0);
                app.setJointPanelTitle(ji, p, v, pwm, home);
            end
            app.ea1 = returnData.nxt.ea1;
            app.ea2 = returnData.nxt.ea2;
            app.ea3 = returnData.nxt.ea3;
            
            
            app.RXTXLabel.Text = {  [num2str(app.nxt.packetsSent), ' packets sent'];   ...
                                    [num2str(app.nxt.packetsReceived), ' packets received'] };
        end
        
        
        % Creates a command packet from UI components, then sends it over bluetooth
        function packet = sendPacketFromUI(app, timer, event)
            persistent pcVarStartIndex;
            if isempty(pcVarStartIndex)
                pcVarStartIndex = strfind(strcmp('j1pt', NXTConnection.PC_PACKET_VARS)', 1);
            end
            
            if app.nxt.connected == true
                % Start with empty packet, fill in fields from state variables
                packet = NXTConnection.PC_BT_EMPTY_PACKET;
                
                var = pcVarStartIndex;
                for ji=1:6
                    if app.PTEnableCheckBoxes(ji).Value == true
                        packet.(NXTConnection.PC_PACKET_VARS{var}) = app.PTSpinners(ji).Value;   var = var+1;
                    else
                        packet.(NXTConnection.PC_PACKET_VARS{var}) = NXTConnection.DISABLE_PT;   var = var+1;
                    end
                    if app.VTMaxCheckBoxes(ji).Value == true
                        packet.(NXTConnection.PC_PACKET_VARS{var}) = NXTConnection.DISABLE_VT;   var = var+1;
                    else
                        packet.(NXTConnection.PC_PACKET_VARS{var}) = app.VTSpinners(ji).Value;   var = var+1;
                    end
                    
                end
                packet.rcx = uint8(app.rcx);
                packet.nxtTransmitInterval = uint16(app.nxtTransmitInterval);
                
                % Send over bluetooth
                app.nxt.bluetoothSend(packet);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('matlab-plot-big-master');
            
            app.initJointPanels();
            app.initPVPlots();
            app.initPTSpinnersAndSliders();
            app.initVTSpinnersAndSliders();
            app.initPTEnableCheckBoxes();
            app.initVTMaxCheckBoxes();
            app.initTimers();
            
            app.nxt = NXTConnection(@app.updateUIFromPacket, @Kinematics.process);

            app.startTimers();
        end

        % Close request function: UIFigure
        function shutdownFcn(app, event)
            stop(app.pcTransmitTimer);
            stop(app.plotUpdateTimer);
            app.nxt.bluetoothDisconnect();
            
            delete(timerfindall);
            delete(gcp('nocreate'));
            delete(app);
            
        end

        % Value changed function: PTEnableCheckBox
        function PTCheckboxValueChanged(app, event)
            joint_index = event.Source.UserData;
            if event.Value == true
                val = app.PTSpinners(joint_index).Value;
            else
                val = NXTConnection.DISABLE_PT;
            end
            app.setPTSpinnerSliderValue(joint_index, val);
        end

        % Value changed function: VTMaxCheckBox
        function VTCheckboxValueChanged(app, event)
            joint_index = event.Source.UserData;
            if event.Value == false
                val = app.VTSpinners(joint_index).Value;
            else
                val = NXTConnection.DISABLE_VT;
            end
            app.setVTSpinnerSliderValue(joint_index, val);
        end

        % Value changed function: BluetoothNameEditField
        function BluetoothNameValueChanged(app, event)
            app.bluetoothName = app.BluetoothNameEditField.Value;
        end

        % Button pushed function: ConnectToNXTButton
        function ConnectToNXTButtonPushed(app, event)
            app.bluetoothName = app.BluetoothNameEditField.Value;
            if app.nxt.connected == false
                app.WritetoXLSXButton.Enable = 'off';
                app.ConnectToNXTButton.Enable = 'off';
                app.ConnectToNXTButton.Text = 'Connecting....';
                drawnow
                app.nxt.bluetoothConnect(app.bluetoothName, app.bluetoothChannel);
            else
                app.nxt.bluetoothDisconnect();
            end
            app.setConnectionPanelValue(app.nxt.connected);
        end

        % Value changing function: PTSlider
        function PTSliderValueChanging(app, event)
            joint_index = event.Source.UserData;
            app.PTSpinners(joint_index).Value = event.Value;
        end

        % Callback function: PTSpinner, PTSpinner
        function PTSpinnerValueChange(app, event)
            joint_index = event.Source.UserData;
            app.PTSliders(joint_index).Value = event.Value;
        end

        % Value changing function: VTSlider
        function VTSliderValueChanging(app, event)
            joint_index = event.Source.UserData;
            app.VTSpinners(joint_index).Value = event.Value;
        end

        % Callback function: VTSpinner, VTSpinner
        function VTSpinnerValueChange(app, event)
            joint_index = event.Source.UserData;
            app.VTSliders(joint_index).Value = event.Value; 
        end

        % Value changed function: VTSlider
        function VTSliderValueChanged(app, event)
            joint_index = event.Source.UserData;
            app.VTSpinners(joint_index).Value = 0;
            app.VTSliders(joint_index).Value = 0; 
        end

        % Button pushed function: ClearHistoryButton
        function ClearHistoryButtonPushed(app, event)
            app.nxt.resetHistory();
            app.RXTXLabel.Text = {  [num2str(app.nxt.packetsSent), ' packets sent'];   ...
                                    [num2str(app.nxt.packetsReceived), ' packets received'] };
        end

        % Button pushed function: WritetoXLSXButton
        function WritetoXLSXButtonPushed(app, event)
            if app.nxt.lastRow == 0
                return
            end
            [file, path] = uiputfile('*.xlsx','Save History','history.xlsx');
            if ~isequal(file, 0) && ~isequal(path, 0)       % user didn't choose cancel
                app.nxt.writeHistory(fullfile(path,file));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @shutdownFcn, true);
            app.RightPanel = uipanel(app.UIFigure);
            app.RobotControlPanel = uipanel(app.RightPanel);
            app.GridLayout = uigridlayout(app.RobotControlPanel);
            app.JointPanel = uipanel(app.GridLayout);
            app.JointPanel.Title = 'J1:  0 deg,   0 deg/s,  0% PWM';
            app.PTSpinner = uispinner(app.JointPanel);
            app.PTSpinner.ValueChangingFcn = createCallbackFcn(app, @PTSpinnerValueChange, true);
            app.PTSpinner.Limits = [-100 100];
            app.PTSpinner.RoundFractionalValues = 'on';
            app.PTSpinner.ValueChangedFcn = createCallbackFcn(app, @PTSpinnerValueChange, true);
            app.VTSpinner = uispinner(app.JointPanel);
            app.VTSpinner.ValueChangingFcn = createCallbackFcn(app, @VTSpinnerValueChange, true);
            app.VTSpinner.Limits = [-100 100];
            app.VTSpinner.RoundFractionalValues = 'on';
            app.VTSpinner.ValueChangedFcn = createCallbackFcn(app, @VTSpinnerValueChange, true);
            app.VTMaxCheckBox = uicheckbox(app.JointPanel);
            app.VTMaxCheckBox.ValueChangedFcn = createCallbackFcn(app, @VTCheckboxValueChanged, true);
            app.VTMaxCheckBox.Text = 'Max V Only';
            app.PTEnableCheckBox = uicheckbox(app.JointPanel);
            app.PTEnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @PTCheckboxValueChanged, true);
            app.PTEnableCheckBox.Text = 'Enable P-Tracking';
            app.PTEnableCheckBox.Value = true;
            app.PTSlider = uislider(app.JointPanel);
            app.PTSlider.Limits = [-100 100];
            app.PTSlider.MajorTicks = [-100 -80 -60 -40 -20 0 20 40 60 80 100];
            app.PTSlider.MajorTickLabels = {''};
            app.PTSlider.ValueChangingFcn = createCallbackFcn(app, @PTSliderValueChanging, true);
            app.PTSlider.MinorTicks = [];
            app.VTSlider = uislider(app.JointPanel);
            app.VTSlider.Limits = [-100 100];
            app.VTSlider.MajorTicks = [-100 -80 -60 -40 -20 0 20 40 60 80 100];
            app.VTSlider.MajorTickLabels = {''};
            app.VTSlider.ValueChangedFcn = createCallbackFcn(app, @VTSliderValueChanged, true);
            app.VTSlider.ValueChangingFcn = createCallbackFcn(app, @VTSliderValueChanging, true);
            app.VTSlider.MinorTicks = [];
            app.PVPlot = uiaxes(app.JointPanel);
            app.PVPlot.XLim = [0 10000];
            app.PVPlot.YLim = [-100 100];
            app.PVPlot.XTick = [];
            app.PVPlot.XTickLabel = '';
            app.JointPanel_2 = uipanel(app.GridLayout);
            app.JointPanel_2.Title = 'J1:  0 deg,   0 deg/s,  0% PWM';
            app.PTSpinner_2 = uispinner(app.JointPanel_2);
            app.PTSpinner_2.Limits = [-100 100];
            app.PTSpinner_2.RoundFractionalValues = 'on';
            app.VTSpinner_2 = uispinner(app.JointPanel_2);
            app.VTSpinner_2.Limits = [-100 100];
            app.VTSpinner_2.RoundFractionalValues = 'on';
            app.VTMaxCheckBox_2 = uicheckbox(app.JointPanel_2);
            app.VTMaxCheckBox_2.Text = 'Max V Only';
            app.PTEnableCheckBox_2 = uicheckbox(app.JointPanel_2);
            app.PTEnableCheckBox_2.Text = 'Enable P-Tracking';
            app.PTEnableCheckBox_2.Value = true;
            app.PTSlider_2 = uislider(app.JointPanel_2);
            app.PTSlider_2.Limits = [-100 100];
            app.PTSlider_2.MajorTicks = [-100 -80 -60 -40 -20 0 20 40 60 80 100];
            app.PTSlider_2.MajorTickLabels = {''};
            app.PTSlider_2.MinorTicks = [];
            app.VTSlider_2 = uislider(app.JointPanel_2);
            app.VTSlider_2.Limits = [-100 100];
            app.VTSlider_2.MajorTicks = [-100 -80 -60 -40 -20 0 20 40 60 80 100];
            app.VTSlider_2.MajorTickLabels = {''};
            app.VTSlider_2.MinorTicks = [];
            app.PVPlot_2 = uiaxes(app.JointPanel_2);
            app.PVPlot_2.XLim = [0 10000];
            app.PVPlot_2.YLim = [-100 100];
            app.PVPlot_2.XTickLabel = '';
            app.JointPanel_3 = uipanel(app.GridLayout);
            app.JointPanel_3.Title = 'J1:  0 deg,   0 deg/s,  0% PWM';
            app.PTSpinner_3 = uispinner(app.JointPanel_3);
            app.PTSpinner_3.Limits = [-100 100];
            app.PTSpinner_3.RoundFractionalValues = 'on';
            app.VTSpinner_3 = uispinner(app.JointPanel_3);
            app.VTSpinner_3.Limits = [-100 100];
            app.VTSpinner_3.RoundFractionalValues = 'on';
            app.VTMaxCheckBox_3 = uicheckbox(app.JointPanel_3);
            app.VTMaxCheckBox_3.Text = 'Max V Only';
            app.PTEnableCheckBox_3 = uicheckbox(app.JointPanel_3);
            app.PTEnableCheckBox_3.Text = 'Enable P-Tracking';
            app.PTEnableCheckBox_3.Value = true;
            app.PTSlider_3 = uislider(app.JointPanel_3);
            app.PTSlider_3.Limits = [-100 100];
            app.PTSlider_3.MajorTicks = [-100 -80 -60 -40 -20 0 20 40 60 80 100];
            app.PTSlider_3.MajorTickLabels = {''};
            app.PTSlider_3.MinorTicks = [];
            app.VTSlider_3 = uislider(app.JointPanel_3);
            app.VTSlider_3.Limits = [-100 100];
            app.VTSlider_3.MajorTicks = [-100 -80 -60 -40 -20 0 20 40 60 80 100];
            app.VTSlider_3.MajorTickLabels = {''};
            app.VTSlider_3.MinorTicks = [];
            app.PVPlot_3 = uiaxes(app.JointPanel_3);
            app.PVPlot_3.XLim = [0 10000];
            app.PVPlot_3.YLim = [-100 100];
            app.PVPlot_3.XTickLabel = '';
            app.JointPanel_4 = uipanel(app.GridLayout);
            app.JointPanel_4.Title = 'J1:  0 deg,   0 deg/s,  0% PWM';
            app.PTSpinner_4 = uispinner(app.JointPanel_4);
            app.PTSpinner_4.Limits = [-100 100];
            app.PTSpinner_4.RoundFractionalValues = 'on';
            app.VTSpinner_4 = uispinner(app.JointPanel_4);
            app.VTSpinner_4.Limits = [-100 100];
            app.VTSpinner_4.RoundFractionalValues = 'on';
            app.VTMaxCheckBox_4 = uicheckbox(app.JointPanel_4);
            app.VTMaxCheckBox_4.Text = 'Max V Only';
            app.PTEnableCheckBox_4 = uicheckbox(app.JointPanel_4);
            app.PTEnableCheckBox_4.Text = 'Enable P-Tracking';
            app.PTEnableCheckBox_4.Value = true;
            app.PTSlider_4 = uislider(app.JointPanel_4);
            app.PTSlider_4.Limits = [-100 100];
            app.PTSlider_4.MajorTicks = [-100 -80 -60 -40 -20 0 20 40 60 80 100];
            app.PTSlider_4.MajorTickLabels = {''};
            app.PTSlider_4.MinorTicks = [];
            app.VTSlider_4 = uislider(app.JointPanel_4);
            app.VTSlider_4.Limits = [-100 100];
            app.VTSlider_4.MajorTicks = [-100 -80 -60 -40 -20 0 20 40 60 80 100];
            app.VTSlider_4.MajorTickLabels = {''};
            app.VTSlider_4.MinorTicks = [];
            app.PVPlot_4 = uiaxes(app.JointPanel_4);
            app.PVPlot_4.XLim = [0 10000];
            app.PVPlot_4.YLim = [-100 100];
            app.PVPlot_4.XTickLabel = '';
            app.JointPanel_5 = uipanel(app.GridLayout);
            app.JointPanel_5.Title = 'J1:  0 deg,   0 deg/s,  0% PWM';
            app.PTSpinner_5 = uispinner(app.JointPanel_5);
            app.PTSpinner_5.Limits = [-100 100];
            app.PTSpinner_5.RoundFractionalValues = 'on';
            app.VTSpinner_5 = uispinner(app.JointPanel_5);
            app.VTSpinner_5.Limits = [-100 100];
            app.VTSpinner_5.RoundFractionalValues = 'on';
            app.VTMaxCheckBox_5 = uicheckbox(app.JointPanel_5);
            app.VTMaxCheckBox_5.Text = 'Max V Only';
            app.PTEnableCheckBox_5 = uicheckbox(app.JointPanel_5);
            app.PTEnableCheckBox_5.Text = 'Enable P-Tracking';
            app.PTEnableCheckBox_5.Value = true;
            app.PTSlider_5 = uislider(app.JointPanel_5);
            app.PTSlider_5.Limits = [-100 100];
            app.PTSlider_5.MajorTicks = [-100 -80 -60 -40 -20 0 20 40 60 80 100];
            app.PTSlider_5.MajorTickLabels = {''};
            app.PTSlider_5.MinorTicks = [];
            app.VTSlider_5 = uislider(app.JointPanel_5);
            app.VTSlider_5.Limits = [-100 100];
            app.VTSlider_5.MajorTicks = [-100 -80 -60 -40 -20 0 20 40 60 80 100];
            app.VTSlider_5.MajorTickLabels = {''};
            app.VTSlider_5.MinorTicks = [];
            app.PVPlot_5 = uiaxes(app.JointPanel_5);
            app.PVPlot_5.XLim = [0 10000];
            app.PVPlot_5.YLim = [-100 100];
            app.PVPlot_5.XTickLabel = '';
            app.JointPanel_6 = uipanel(app.GridLayout);
            app.JointPanel_6.Title = 'J1:  0 deg,   0 deg/s,  0% PWM';
            app.PTSpinner_6 = uispinner(app.JointPanel_6);
            app.PTSpinner_6.Limits = [-100 100];
            app.PTSpinner_6.RoundFractionalValues = 'on';
            app.VTSpinner_6 = uispinner(app.JointPanel_6);
            app.VTSpinner_6.Limits = [-100 100];
            app.VTSpinner_6.RoundFractionalValues = 'on';
            app.VTMaxCheckBox_6 = uicheckbox(app.JointPanel_6);
            app.VTMaxCheckBox_6.Text = 'Max V Only';
            app.PTEnableCheckBox_6 = uicheckbox(app.JointPanel_6);
            app.PTEnableCheckBox_6.Text = 'Enable P-Tracking';
            app.PTEnableCheckBox_6.Value = true;
            app.PTSlider_6 = uislider(app.JointPanel_6);
            app.PTSlider_6.Limits = [-100 100];
            app.PTSlider_6.MajorTicks = [-100 -80 -60 -40 -20 0 20 40 60 80 100];
            app.PTSlider_6.MajorTickLabels = {''};
            app.PTSlider_6.MinorTicks = [];
            app.VTSlider_6 = uislider(app.JointPanel_6);
            app.VTSlider_6.Limits = [-100 100];
            app.VTSlider_6.MajorTicks = [-100 -80 -60 -40 -20 0 20 40 60 80 100];
            app.VTSlider_6.MajorTickLabels = {''};
            app.VTSlider_6.MinorTicks = [];
            app.PVPlot_6 = uiaxes(app.JointPanel_6);
            app.PVPlot_6.XLim = [0 10000];
            app.PVPlot_6.YLim = [-100 100];
            app.PVPlot_6.XTickLabel = '';
            app.LeftPanel = uipanel(app.UIFigure);
            app.ConnectionPanel = uipanel(app.LeftPanel);
            app.ConnectionPanel.Title = 'NXT Connection';
            app.BluetoothNameLabel = uilabel(app.ConnectionPanel);
            app.BluetoothNameLabel.Text = 'Bluetooth Name:';
            app.BluetoothNameEditField = uieditfield(app.ConnectionPanel, 'text');
            app.BluetoothNameEditField.ValueChangedFcn = createCallbackFcn(app, @BluetoothNameValueChanged, true);
            app.BluetoothNameEditField.Value = 'NXT1';
            app.ConnectToNXTButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectToNXTButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectToNXTButtonPushed, true);
            app.ConnectToNXTButton.Text = 'Connect to NXT';
            app.RXTXLabel = uilabel(app.ConnectionPanel);
            app.RXTXLabel.Text = {'0 packets sent'; '0 packets received'};
            app.ClearHistoryButton = uibutton(app.ConnectionPanel, 'push');
            app.ClearHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @ClearHistoryButtonPushed, true);
            app.ClearHistoryButton.Text = 'Clear History';
            app.WritetoXLSXButton = uibutton(app.ConnectionPanel, 'push');
            app.WritetoXLSXButton.ButtonPushedFcn = createCallbackFcn(app, @WritetoXLSXButtonPushed, true);
            app.WritetoXLSXButton.Text = 'Write to XLSX';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RA15_Control_Panel

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end